classdef Receiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RetryConnectionButton  matlab.ui.control.Button
        ConnectingStatus       matlab.ui.control.Label
        ConnectionStatusLabel  matlab.ui.control.Label
        StatusButton           matlab.ui.control.StateButton
    end

    
    methods (Access = private)
        
        function startReception(app)
            global rx;
            global time_scope;

            show(time_scope);
            for i= 0:inf
                if (app.StatusButton.Value == 0)
                    break;
                end
                data = rx();
                time_scope(data)
                app.StatusButton.Text = "Receiving...";
                app.StatusButton.FontColor = [0,1,0];
                drawnow();
            end
            release(time_scope);
            release(rx);
        end
        
        function rx = establishConnection(app)
            center_frequency = 13560000;
            sampling_rate = 2000000;
            decimation_factor = round(100000000/sampling_rate);
            
            app.ConnectingStatus.Visible = true;
            app.ConnectingStatus.Text = 'Connecting...';
            app.ConnectingStatus.FontColor = [1,1,0];
            drawnow();

            rx = comm.SDRuReceiver(...
                    Platform = "N200/N210/USRP2", ...
                    IPAddress = '192.168.10.2', ...
                    OutputDataType = "double", ...
                    DecimationFactor = decimation_factor, ...
                    Gain = 0, ...
                    CenterFrequency = center_frequency, ...
                    SamplesPerFrame = 50000);
            try
                rx();
            catch
                app.ConnectingStatus.Text = 'Connection Not Successful';
                app.ConnectingStatus.FontColor = [1,0,0];
                app.RetryConnectionButton.Enable = true;
                release(rx);
                return;
            end
            app.StatusButton.Enable = true;
            app.ConnectingStatus.Text = "Connected";
            app.ConnectingStatus.FontColor = [0,1,0];
        end
        
        function time_scope = createTimeScope(app)
            sampling_rate = 2000000;

            time_scope = timescope(TimeSpanSource = "auto",...
                      TimeSpan = 1,SampleRate = sampling_rate, ...
                      Position=[20,200,800,500], ...
                      YLimits=[-.6,.6]);
            hide(time_scope);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusButton.Enable = false;
            app.RetryConnectionButton.Enable = false;
            drawnow();
            global rx;
            global time_scope;
            rx = establishConnection(app);
            time_scope = createTimeScope(app);

        end

        % Value changed function: StatusButton
        function StatusButtonValueChanged(app, event)
            value = app.StatusButton.Value;
            if value == true
               app.StatusButton.Text = 'Starting Reception...';
               app.StatusButton.FontColor = [1,1,0];
               drawnow();
               startReception(app);
            else
               app.StatusButton.Text = "Not Receiving";
               app.StatusButton.FontColor = [1,0,0];
            end
        end

        % Button pushed function: RetryConnectionButton
        function RetryConnectionButtonPushed(app, event)
            establishConnection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusButton = uibutton(app.UIFigure, 'state');
            app.StatusButton.ValueChangedFcn = createCallbackFcn(app, @StatusButtonValueChanged, true);
            app.StatusButton.Text = 'Not Receiving';
            app.ConnectionStatusLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLabel.Text = 'Connection Status: ';
            app.ConnectingStatus = uilabel(app.UIFigure);
            app.ConnectingStatus.Visible = 'off';
            app.ConnectingStatus.Text = 'Connecting...';
            app.RetryConnectionButton = uibutton(app.UIFigure, 'push');
            app.RetryConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @RetryConnectionButtonPushed, true);
            app.RetryConnectionButton.Text = 'Retry Connection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end